classdef IHM_Control_Robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DumboControlUIFigure        matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        PaneldecontrolTab           matlab.ui.container.Tab
        ComenzarButton              matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        EnviarButton                matlab.ui.control.Button
        SimularButton               matlab.ui.control.Button
        PosicinPanel                matlab.ui.container.Panel
        Dir1                        matlab.ui.control.Spinner
        Amp1                        matlab.ui.control.Spinner
        RtulaLabel                  matlab.ui.control.Label
        DireccinLabel               matlab.ui.control.Label
        AmplitudLabel               matlab.ui.control.Label
        Label_1                     matlab.ui.control.Label
        Dir2                        matlab.ui.control.Spinner
        Amp2                        matlab.ui.control.Spinner
        Label_2                     matlab.ui.control.Label
        Dir3                        matlab.ui.control.Spinner
        Dir4                        matlab.ui.control.Spinner
        Dir5                        matlab.ui.control.Spinner
        Dir6                        matlab.ui.control.Spinner
        Dir7                        matlab.ui.control.Spinner
        Amp3                        matlab.ui.control.Spinner
        Amp4                        matlab.ui.control.Spinner
        Amp5                        matlab.ui.control.Spinner
        Amp6                        matlab.ui.control.Spinner
        Amp7                        matlab.ui.control.Spinner
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        ParmetrosPanel_3            matlab.ui.container.Panel
        AcelTsLabel                 matlab.ui.control.Label
        AcelT                       matlab.ui.control.NumericEditField
        DcelTsLabel                 matlab.ui.control.Label
        DcelT                       matlab.ui.control.NumericEditField
        VelocidadSliderLabel        matlab.ui.control.Label
        VelocidadSlider             matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        ConexinLampLabel            matlab.ui.control.Label
        ConexinLamp                 matlab.ui.control.Lamp
        TiemposLabel                matlab.ui.control.Label
        Time_Indicador              matlab.ui.control.SemicircularGauge
        CargarSiguienteButton       matlab.ui.control.Button
        CargarFicheroButton         matlab.ui.control.Button
        CargarAnteriorButton        matlab.ui.control.Button
        RunCheckBox                 matlab.ui.control.CheckBox
        RutaficheroEditFieldLabel   matlab.ui.control.Label
        RutaficheroEditField        matlab.ui.control.EditField
        PuntoLabel                  matlab.ui.control.Label
        ControlIncrementalTab       matlab.ui.container.Tab
        PosicinPanel_2              matlab.ui.container.Panel
        Mot_1                       matlab.ui.control.Spinner
        Dist_1                      matlab.ui.control.Spinner
        CableLabel                  matlab.ui.control.Label
        MotorLabel                  matlab.ui.control.Label
        DistanciammLabel            matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Mot_2                       matlab.ui.control.Spinner
        Dist_2                      matlab.ui.control.Spinner
        Label_9                     matlab.ui.control.Label
        Mot_3                       matlab.ui.control.Spinner
        Dist_3                      matlab.ui.control.Spinner
        Label_10                    matlab.ui.control.Label
        GoButton                    matlab.ui.control.Button
        PosicinPanel_3              matlab.ui.container.Panel
        Dir1_2                      matlab.ui.control.Spinner
        Amp1_2                      matlab.ui.control.Spinner
        RtulaLabel_2                matlab.ui.control.Label
        DireccinLabel_2             matlab.ui.control.Label
        AmplitudLabel_2             matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        Dir2_2                      matlab.ui.control.Spinner
        Amp2_2                      matlab.ui.control.Spinner
        Label_12                    matlab.ui.control.Label
        Dir3_2                      matlab.ui.control.Spinner
        Dir4_2                      matlab.ui.control.Spinner
        Dir5_2                      matlab.ui.control.Spinner
        Dir6_2                      matlab.ui.control.Spinner
        Dir7_2                      matlab.ui.control.Spinner
        Amp3_2                      matlab.ui.control.Spinner
        Amp4_2                      matlab.ui.control.Spinner
        Amp5_2                      matlab.ui.control.Spinner
        Amp6_2                      matlab.ui.control.Spinner
        Amp7_2                      matlab.ui.control.Spinner
        Label_13                    matlab.ui.control.Label
        Label_14                    matlab.ui.control.Label
        Label_15                    matlab.ui.control.Label
        Label_16                    matlab.ui.control.Label
        Label_17                    matlab.ui.control.Label
        ParmetrosPanel_4            matlab.ui.container.Panel
        AcelTsLabel_2               matlab.ui.control.Label
        AcelT_2                     matlab.ui.control.NumericEditField
        DcelTsLabel_2               matlab.ui.control.Label
        DcelT_2                     matlab.ui.control.NumericEditField
        VelocidadSlider_2Label      matlab.ui.control.Label
        VelocidadSlider_2           matlab.ui.control.Slider
        TipoButtonGroup             matlab.ui.container.ButtonGroup
        DistanciaIncrementalButton  matlab.ui.control.RadioButton
        nguloIncrementalButton      matlab.ui.control.RadioButton
        UnidadesButtonGroup         matlab.ui.container.ButtonGroup
        mmButton                    matlab.ui.control.RadioButton
        PulsosButton                matlab.ui.control.RadioButton
        PosicionesButton            matlab.ui.control.Button
        ComunicacionTab             matlab.ui.container.Tab
        ParmetrosPanel              matlab.ui.container.Panel
        PuertoDropDownLabel         matlab.ui.control.Label
        Puerto                      matlab.ui.control.DropDown
        BaudrateLabel               matlab.ui.control.Label
        Baudrate                    matlab.ui.control.DropDown
        ConectarButton              matlab.ui.control.Button
        RButton                     matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function update_input_values(app)
            global Vmax AcelT DcelT alpha beta Mot_I V_Tr;
            %%Definición de Variables
            Mot_I = [ 1,2,4,5,7,8,10,11,13,14,16,17,19,20,22,23,25,26,28,29,31,32,34,35 ];
            V_Tr=zeros(1,36);
            
            %%Configuration Parameters
            Vmax=app.VelocidadSlider.Value;
            AcelT=app.AcelT.Value;
            DcelT=app.DcelT.Value;
            alpha=[app.Dir1.Value app.Dir2.Value app.Dir3.Value app.Dir4.Value app.Dir5.Value app.Dir6.Value app.Dir7.Value 0];    %Direccion de maxima pendiente
            beta=[app.Amp1.Value app.Amp2.Value app.Amp3.Value app.Amp4.Value app.Amp5.Value app.Amp6.Value app.Amp7.Value 0];     %Angulo de giro
            
        end
        
        function update_input_values_manual(app)
            global Vmax AcelT DcelT alpha beta Mot_I V_Tr mov_m mov_d;
            %%Definición de Variables
            Mot_I = [ 1,2,4,5,7,8,10,11,13,14,16,17,19,20,22,23,25,26,28,29,31,32,34,35 ];
            V_Tr=zeros(1,36);
            
            %%Configuration Parameters
            Vmax=app.VelocidadSlider_2.Value;
            AcelT=app.AcelT_2.Value;
            DcelT=app.DcelT_2.Value;
            alpha=[app.Dir1_2.Value app.Dir2_2.Value app.Dir3_2.Value app.Dir4_2.Value app.Dir5_2.Value app.Dir6_2.Value app.Dir7_2.Value 0];    %Direccion de maxima pendiente
            beta=[app.Amp1_2.Value app.Amp2_2.Value app.Amp3_2.Value app.Amp4_2.Value app.Amp5_2.Value app.Amp6_2.Value app.Amp7_2.Value 0];     %Angulo de giro
            mov_m=[app.Mot_1.Value app.Mot_2.Value app.Mot_3.Value];
            mov_d=[app.Dist_1.Value app.Dist_1.Value app.Dist_1.Value];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %addpath('/Icons');
            hold (app.UIAxes, 'on');
            %%axis(app.UIAxes, 'equal');
            %axis(app.UIAxes,[-400 400 -400 400 -300 900])
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            zlabel(app.UIAxes,'z');
            %camtarget(app.UIAxes, [0 100 300]);
            campos(app.UIAxes, [-2500 1500 3500]);
            camup(app.UIAxes, [0 1 0]);
            %%rotate3d(app.UIAxes, 'on');
            
            SizeChanged(app);
            app.Puerto.Items=seriallist;
            app.Baudrate.Items={'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
            app.Baudrate.Value = {'115200'};
            
            UnidadesButtonGroupSelectionChanged(app);
            TipoButtonGroupSelectionChanged(app);
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            global alpha beta;
            set(app.SimularButton,'BackgroundColor',[0.94 0.94 0.94])
            pause(0.001); %%Por algun extraño motivo si no pongo el delay no actualiza el color, who knows?¿
            
            %%------------------------
            update_input_values(app);
            
            %%Se dibuja la posicion del robot
            Dibujar_Robot_App(app,Calc_Posiciones(alpha,beta));
            
            
            set(app.SimularButton,'BackgroundColor','green')
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Dir1.Value=0;
            app.Dir2.Value=0;
            app.Dir3.Value=0;
            app.Dir4.Value=0;
            app.Dir5.Value=0;
            app.Dir6.Value=0;
            app.Dir7.Value=0;
            app.Amp1.Value=0;
            app.Amp2.Value=0;
            app.Amp3.Value=0;
            app.Amp4.Value=0;
            app.Amp5.Value=0;
            app.Amp6.Value=0;
            app.Amp7.Value=0;
            startupFcn(app);
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            %Parte Envio
            global Arduino Vmax AcelT DcelT alpha beta V_Tr Mot_I Time Output;
            set(app.EnviarButton,'BackgroundColor',[0.94 0.94 0.94]);
            app.Time_Indicador.Value=0;
            %%------------------------
            update_input_values(app);
            %%Se calculan las posiciones de todas las rotulas a partir de los angulos y se calculan los pulsos de destino.
            Puntos_Moved_Red=Calc_Posiciones(alpha,beta);
            Pulsos_Destino = Calc_Pulsos(Puntos_Moved_Red);
            
            %%Se solicita a la placa Main las posiciones actuales de todos los motores para poder calcular las trayectorias
            PulsesIni=Serial(Arduino,"Pos");
            disp(PulsesIni);
            
            %%Se analiza la trayectoria mas desfavorable para calcular el tiempo minimo necesario para la trayectoria.
            [M,I] = max(abs(Pulsos_Destino(Mot_I)-PulsesIni(Mot_I)));
            [Time,] = Calc_T_V(Vmax,AcelT,DcelT,PulsesIni(Mot_I(I)),Pulsos_Destino(Mot_I(I)),0,0,0,0);
            
            %%Se calculan las velocidades maximas de los trapecios de velocidad para cada motor.
            for i=1:24
                %V_Trs(Mot_I(i))=double(Calc_V(AcelT,DcelT,PulsesIni,Pulsos_Destino(Mot_I(i)),Time));
                V_Tr(Mot_I(i))=(2*PulsesIni(Mot_I(i)) - 2*Pulsos_Destino(Mot_I(i)))/(AcelT + DcelT - 2*Time);
                
                
            end
            
            %%Se envian a la placa Main la configuracion y las velocidades maximas de los trapecios.
            Serial(Arduino,"Conf",Time,'Speed',Vmax,'AcelT',AcelT,'DcelT',DcelT);
            Serial(Arduino,"Tr",V_Tr);
            
            
            if Time<=0
                app.Time_Indicador.Limits=[0 1];
            else
                app.Time_Indicador.Limits=[0 Time];
            end
            
            disp(['Tiempo Trayectoria: ' num2str(Time)]);
            
            for i=1:20
                if Arduino.BytesAvailable
                    Output=fscanf(Arduino,'%s');
                    disp(['Trayectorias Enviadas Correctamente: ' Output]);
                    if Output=="OK"
                        set(app.EnviarButton,'BackgroundColor','green');
                        break;
                    end
                else
                    pause(0.2);
                end
            end
            disp("FIN");
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            global Arduino;
            
            if app.ConectarButton.Text== "Conectar"
                delete(instrfindall);
                Arduino = serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
                set(Arduino,'DataBits',8);
                set(Arduino,'StopBits',1);
                set(Arduino,'Parity','none');
                set(Arduino,'Timeout',0.5);
                try
                    fopen(Arduino);
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[0.94 0.94 0.94]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
                        pause(0.3);
                    end
                    set(app.ConexinLamp,'Color','green');
                    set(app.ConectarButton,'Text','Desconectar');
                catch
                    set(app.ConectarButton,'BackgroundColor','red');
                    msgbox("Conexión Incorrecta");
                end
            else
                try
                    fclose(Arduino);
                    set(app.ConectarButton,'BackgroundColor',[0.94 0.94 0.94]);
                    set(app.ConexinLamp,'Color','red');
                    set(app.ConectarButton,'Text','Conectar');
                catch
                    msgbox("Desconexion Fallida");
                end
            end


        end

        % Size changed function: DumboControlUIFigure, 
        % PaneldecontrolTab
        function SizeChanged(app, event)
            %app.ParmetrosPanel_3.Position=[0 0 0.5 0.1];
            Margins=[10 10 10 10]; %Top Right Bottom Left
            
            set(app.TabGroup,'Position',[
                Margins(4)
                Margins(3)
                app.DumboControlUIFigure.Position(3)-Margins(2)-Margins(4)
                app.DumboControlUIFigure.Position(4)-Margins(1)-Margins(3)]);
            
            set(app.PosicinPanel,'OuterPosition',[
                app.PaneldecontrolTab.Position(1)+app.PaneldecontrolTab.Position(3)-Margins(2)-app.PosicinPanel.OuterPosition(3)
                app.PaneldecontrolTab.Position(2)+app.PaneldecontrolTab.Position(4)-Margins(1)-app.PosicinPanel.OuterPosition(4)
                app.PosicinPanel.OuterPosition(3)
                app.PosicinPanel.OuterPosition(4)]);
            
            set(app.ParmetrosPanel_3,'OuterPosition',[
                app.PosicinPanel.Position(1)
                app.PosicinPanel.Position(2)-Margins(1)-app.ParmetrosPanel_3.OuterPosition(4)
                app.PosicinPanel.OuterPosition(3)
                app.ParmetrosPanel_3.OuterPosition(4)]);
            
            set(app.EnviarButton,'Position',[
                app.ParmetrosPanel_3.Position(1)+app.ParmetrosPanel_3.Position(3)/2-app.EnviarButton.OuterPosition(3)/2
                app.ParmetrosPanel_3.Position(2)-Margins(1)-app.EnviarButton.OuterPosition(4)
                app.EnviarButton.OuterPosition(3)
                app.EnviarButton.OuterPosition(4)]);
            
            set(app.SimularButton,'Position',[
                app.EnviarButton.Position(1)-Margins(1)-app.EnviarButton.Position(3)
                app.EnviarButton.Position(2)
                app.EnviarButton.OuterPosition(3)
                app.EnviarButton.OuterPosition(4)]);
            
            set(app.ComenzarButton,'Position',[
                app.EnviarButton.Position(1)+Margins(4)+app.EnviarButton.Position(3)
                app.EnviarButton.Position(2)
                app.EnviarButton.OuterPosition(3)
                app.EnviarButton.OuterPosition(4)]);
            
            set(app.ResetButton,'Position',[
                app.EnviarButton.Position(1)-Margins(4)/2-app.EnviarButton.Position(3)/2
                app.SimularButton.Position(2)-Margins(1)-app.ResetButton.Position(4)
                app.SimularButton.OuterPosition(3)
                app.ResetButton.OuterPosition(4)]);
            
            set(app.HomeButton,'Position',[
                app.EnviarButton.Position(1)+Margins(4)/2+app.EnviarButton.Position(3)/2
                app.SimularButton.Position(2)-Margins(1)-app.ResetButton.Position(4)
                app.SimularButton.OuterPosition(3)
                app.ResetButton.OuterPosition(4)]);
            
            %%REVISAR
            set(app.UIAxes,'OuterPosition',[
                app.PaneldecontrolTab.Position(1)+Margins(4)
                app.ParmetrosPanel_3.Position(2)
                app.PosicinPanel.Position(1)-app.PaneldecontrolTab.Position(1) - Margins(2)- Margins(4)
                app.PosicinPanel.OuterPosition(2) + app.PosicinPanel.OuterPosition(4) - app.ParmetrosPanel_3.OuterPosition(2)]);
            
        end

        % Button pushed function: RButton
        function RefreshCOM(app, event)
            app.Puerto.Items=seriallist;
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            global Arduino Time;
            set(app.EnviarButton,'BackgroundColor',[0.94 0.94 0.94]);
            Serial(Arduino,"Ini");
            for i=0:Time/100:Time
                app.Time_Indicador.Value=i;
                pause(Time/100);
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            %Parte Envio
            global Arduino Vmax AcelT DcelT V_Tr Mot_I Time mov_m mov_d alpha beta;
            Paso_husillo=4;%mm
            Pulsos_por_rev=44;
            Reductora_Motor= [51,27,0,27,27, 0,51,27,0,27, 27,0,51,27,0, 27,27,0,51,27, 0,27,27,0,51, 27,0,27,27,0, 51,27,0,27,27, 0 ];
            if app.DistanciaIncrementalButton.Value
                %%------------------------
                update_input_values_manual(app);
                %%Se solicita a la placa Main las posiciones actuales de todos los motores para poder calcular las trayectorias
                PulsesIni=Serial(Arduino,"Pos");
                
                %%Se van a mover solo los que cambie, el resto se quedan donde estan.
                Pulsos_Destino = PulsesIni;
                disp(PulsesIni([1 13 25]));
                
                
                for i=1:3
                    if mov_m(i)~=0
                        if app.mmButton.Value
                            Pulsos_Destino(mov_m(i))=PulsesIni(mov_m(i))-round(mov_d(i)*(Pulsos_por_rev/Paso_husillo)*Reductora_Motor(mov_m(i)));
                        elseif app.PulsosButton.Value
                            Pulsos_Destino(mov_m(i))=PulsesIni(mov_m(i))-mov_d(i); %%Si esta en pulsos, se mete el valor directamente
                        end
                    end
                end
                
                
                %%Se analiza la trayectoria mas desfavorable para calcular el tiempo minimo necesario para la trayectoria.
                [M,I] = max(abs(Pulsos_Destino(Mot_I)-PulsesIni(Mot_I)));
                [Time,] = Calc_T_V(Vmax,AcelT,DcelT,PulsesIni(Mot_I(I)),Pulsos_Destino(Mot_I(I)),0,0,0,0);
                
                %%Se calculan las velocidades maximas de los trapecios de velocidad para cada motor.
                for i=1:24
                    V_Tr(Mot_I(i))=(2*PulsesIni(Mot_I(i)) - 2*Pulsos_Destino(Mot_I(i)))/(AcelT + DcelT - 2*Time);
                end
                
                
            elseif app.nguloIncrementalButton.Value
                %%------------------------
                update_input_values_manual(app);
                PulsesIni=Serial(Arduino,"Pos");
                
                % Definición de cables asociados a cada rótula.
                Geomatriz(1,2:4)=[1,13,25];
                Geomatriz(2,2:4)=[7,19,31];
                Geomatriz(3,2:4)=[4,16,28];
                Geomatriz(4,2:4)=[10,22,34];
                Geomatriz(5,2:4)=[5,17,29];
                Geomatriz(6,2:4)=[11,23,35];
                Geomatriz(7,2:4)=[2,14,26];
                Geomatriz(8,2:4)=[8,20,32];
                
                %%Se calculan las posiciones de todas las rotulas a partir de los angulos y se calculan los pulsos de destino.
                Puntos_Moved_Red=Calc_Posiciones(alpha,beta);
                Pulsos_Destino=zeros(1,36);
                for i=1:8
                    Aux_Distancias= Calc_Distancia(i,Puntos_Moved_Red);
                    Pulsos_Destino(Geomatriz(i,2:4)) = PulsesIni(Geomatriz(i,2:4))-(Aux_Distancias(Geomatriz(i,2:4))-50)*(Pulsos_por_rev/Paso_husillo).*Reductora_Motor(Geomatriz(i,2:4));
                end
                %%Se solicita a la placa Main las posiciones actuales de todos los motores para poder calcular las trayectorias
                
                Aux_Distancias= Calc_Distancia(1,Puntos_Moved_Red);
                disp(Aux_Distancias(Geomatriz(1,2:4))-50);
                
                %%Se analiza la trayectoria mas desfavorable para calcular el tiempo minimo necesario para la trayectoria.
                [M,I] = max(abs(Pulsos_Destino(Mot_I)-PulsesIni(Mot_I)));
                [Time,] = Calc_T_V(Vmax,AcelT,DcelT,PulsesIni(Mot_I(I)),Pulsos_Destino(Mot_I(I)),0,0,0,0);
                
                %%Se calculan las velocidades maximas de los trapecios de velocidad para cada motor.
                for i=1:24
                    V_Tr(Mot_I(i))=(2*PulsesIni(Mot_I(i)) - 2*Pulsos_Destino(Mot_I(i)))/(AcelT + DcelT - 2*Time);
                end
            end
            %%Se envian a la placa Main la configuracion y las velocidades maximas de los trapecios.
            Serial(Arduino,"Conf",Time,'Speed',Vmax,'AcelT',AcelT,'DcelT',DcelT);
            Serial(Arduino,"Tr",V_Tr);
            
            disp(['Tiempo Trayectoria: ' num2str(Time)]);
            
            for i=1:20
                if Arduino.BytesAvailable
                    Output=fscanf(Arduino,'%s');
                    disp(['Trayectorias Enviadas Correctamente: ' Output]);
                    if Output=="OK"
                        set(app.GoButton,'BackgroundColor','green');
                        Serial(Arduino,"Ini");
                        break;
                    end
                else
                    pause(0.2);
                end
            end
            disp("FIN");
            
            
        end

        % Selection changed function: UnidadesButtonGroup
        function UnidadesButtonGroupSelectionChanged(app, event)
            selectedButton = app.UnidadesButtonGroup.SelectedObject;
            set(app.Dist_1, 'Value',0);
            set(app.Dist_2, 'Value',0);
            set(app.Dist_3, 'Value',0);
            if selectedButton.Text =="mm"
                app.DistanciammLabel.Text = "Distancia (mm)";
                set(app.Dist_1, 'Step',0.1)
                set(app.Dist_1, 'Limits',[-300 300]);
                set(app.Dist_1, 'ValueDisplayFormat','%.2f');
                set(app.Dist_2, 'Step',0.1);
                set(app.Dist_2, 'Limits',[-300 300]);
                set(app.Dist_2, 'ValueDisplayFormat','%.2f');
                set(app.Dist_3, 'Step',0.1);
                set(app.Dist_3, 'Limits',[-300 300]);
                set(app.Dist_3, 'ValueDisplayFormat','%.2f');
            elseif selectedButton.Text =="Pulsos"
                app.DistanciammLabel.Text = "Pulsos";
                set(app.Dist_1, 'Step',1);
                set(app.Dist_1, 'Limits',[-inf inf]);
                set(app.Dist_1, 'ValueDisplayFormat','%d');
                set(app.Dist_2, 'Step',1);
                set(app.Dist_2, 'Limits',[-inf inf]);
                set(app.Dist_2, 'ValueDisplayFormat','%d');
                set(app.Dist_3, 'Step',1);
                set(app.Dist_3, 'Limits',[-inf inf]);
                set(app.Dist_3, 'ValueDisplayFormat','%d');
            end
            
        end

        % Selection changed function: TipoButtonGroup
        function TipoButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipoButtonGroup.SelectedObject;
            if selectedButton.Text =="Distancia Incremental"
                app.PosicinPanel_2.BackgroundColor = [0.9290, 0.6940, 0.1250];
                set(app.PosicinPanel_2,'visible','on');
                set(app.UnidadesButtonGroup,'visible','on');
                set(app.PosicinPanel_3,'visible','off');
                
            elseif selectedButton.Text =="Ángulo Incremental"
                app.PosicinPanel_3.BackgroundColor = [0.9290, 0.6940, 0.1250];
                set(app.PosicinPanel_3,'visible','on');
                set(app.UnidadesButtonGroup,'visible','off');
                set(app.PosicinPanel_2,'visible','off');
                
            end
        end

        % Button pushed function: PosicionesButton
        function PosicionesButtonPushed(app, event)
            global Arduino;
            PulsesIni=Serial(Arduino,"Pos");
            disp(PulsesIni);
        end

        % Button pushed function: CargarSiguienteButton
        function CargarSiguienteButtonPushed(app, event)
            %load caca.m;
            global Contador_Carga direccion amplitud Output Puntos;
            
            try
                if(Contador_Carga>Puntos)
                    Contador_Carga=Puntos;
                end
                app.PuntoLabel.Text="Punto: " + string(Contador_Carga) +" de "+ string(Puntos);
                
                for i=1:7
                    app.(sprintf('Dir%d', i)).Value=direccion(Contador_Carga,i);
                end
                
                for i=1:7
                    app.(sprintf('Amp%d', i)).Value=amplitud(Contador_Carga,i);
                end
                Contador_Carga=Contador_Carga+1;
                
                if app.RunCheckBox.Value
                    EnviarButtonPushed(app);
                    if (Output=="OK")
                        pause (0.2);
                        ComenzarButtonPushed(app);
                    end
                end
                
            catch
                msgbox("Fuera de Indices");
            end
        end

        % Button pushed function: CargarFicheroButton
        function CargarFicheroButtonPushed(app, event)
            global Contador_Carga direccion amplitud Puntos;
            Tr=load(app.RutaficheroEditField.Value);
            direccion=Tr.direccionIVAN;
            amplitud=Tr.amplitudIVAN;
            Puntos=min(size(direccion,1),size(amplitud,1));
            Contador_Carga=1;
            app.CargarFicheroButton.BackgroundColor='Green';
            app.PuntoLabel.Text="Punto: " + "Presiona ""Cargar Siguiente"" para cargar el primer punto.";
        end

        % Button pushed function: CargarAnteriorButton
        function CargarAnteriorButtonPushed(app, event)
            global Contador_Carga direccion amplitud Output Puntos;
            
            Contador_Carga=Contador_Carga-2;
            if(Contador_Carga<1)
                Contador_Carga=1;
            end
            app.PuntoLabel.Text="Punto: " + string(Contador_Carga) +" de "+ string(Puntos);
            
            try
                for i=1:7
                    app.(sprintf('Dir%d', i)).Value=direccion(Contador_Carga,i);
                end
                
                for i=1:7
                    app.(sprintf('Amp%d', i)).Value=amplitud(Contador_Carga,i);
                end
                Contador_Carga=Contador_Carga+1;
                
                if app.RunCheckBox.Value
                    EnviarButtonPushed(app);
                    if (Output=="OK")
                        pause (0.2);
                        ComenzarButtonPushed(app);
                    end
                end
            catch
                msgbox("Fuera de Indices");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DumboControlUIFigure = uifigure;
            app.DumboControlUIFigure.Name = 'Dumbo Control';
            app.DumboControlUIFigure.SizeChangedFcn = createCallbackFcn(app, @SizeChanged, true);
            app.TabGroup = uitabgroup(app.DumboControlUIFigure);
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.SizeChangedFcn = createCallbackFcn(app, @SizeChanged, true);
            app.PaneldecontrolTab.Title = 'Panel de control';
            app.ComenzarButton = uibutton(app.PaneldecontrolTab, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.ResetButton = uibutton(app.PaneldecontrolTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EnviarButton = uibutton(app.PaneldecontrolTab, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.SimularButton = uibutton(app.PaneldecontrolTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.PosicinPanel = uipanel(app.PaneldecontrolTab);
            app.PosicinPanel.Title = 'Posición';
            app.Dir1 = uispinner(app.PosicinPanel);
            app.Dir1.Limits = [0 365];
            app.Amp1 = uispinner(app.PosicinPanel);
            app.Amp1.Limits = [-33 33];
            app.RtulaLabel = uilabel(app.PosicinPanel);
            app.RtulaLabel.Text = 'Rótula';
            app.DireccinLabel = uilabel(app.PosicinPanel);
            app.DireccinLabel.Text = 'Dirección';
            app.AmplitudLabel = uilabel(app.PosicinPanel);
            app.AmplitudLabel.Text = 'Amplitud';
            app.Label_1 = uilabel(app.PosicinPanel);
            app.Label_1.Text = '1';
            app.Dir2 = uispinner(app.PosicinPanel);
            app.Dir2.Limits = [0 365];
            app.Amp2 = uispinner(app.PosicinPanel);
            app.Amp2.Limits = [-33 33];
            app.Label_2 = uilabel(app.PosicinPanel);
            app.Label_2.Text = '2';
            app.Dir3 = uispinner(app.PosicinPanel);
            app.Dir3.Limits = [0 365];
            app.Dir4 = uispinner(app.PosicinPanel);
            app.Dir4.Limits = [0 365];
            app.Dir5 = uispinner(app.PosicinPanel);
            app.Dir5.Limits = [0 365];
            app.Dir6 = uispinner(app.PosicinPanel);
            app.Dir6.Limits = [0 365];
            app.Dir7 = uispinner(app.PosicinPanel);
            app.Dir7.Limits = [0 365];
            app.Amp3 = uispinner(app.PosicinPanel);
            app.Amp3.Limits = [-33 33];
            app.Amp4 = uispinner(app.PosicinPanel);
            app.Amp4.Limits = [-33 33];
            app.Amp5 = uispinner(app.PosicinPanel);
            app.Amp5.Limits = [-33 33];
            app.Amp6 = uispinner(app.PosicinPanel);
            app.Amp6.Limits = [-33 33];
            app.Amp7 = uispinner(app.PosicinPanel);
            app.Amp7.Limits = [-33 33];
            app.Label_3 = uilabel(app.PosicinPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.PosicinPanel);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.PosicinPanel);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.PosicinPanel);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.PosicinPanel);
            app.Label_7.Text = '7';
            app.ParmetrosPanel_3 = uipanel(app.PaneldecontrolTab);
            app.ParmetrosPanel_3.Title = 'Parámetros';
            app.AcelTsLabel = uilabel(app.ParmetrosPanel_3);
            app.AcelTsLabel.Text = 'AcelT (s)';
            app.AcelT = uieditfield(app.ParmetrosPanel_3, 'numeric');
            app.AcelT.Limits = [0.3 Inf];
            app.AcelT.Value = 0.4;
            app.DcelTsLabel = uilabel(app.ParmetrosPanel_3);
            app.DcelTsLabel.Text = 'DcelT (s)';
            app.DcelT = uieditfield(app.ParmetrosPanel_3, 'numeric');
            app.DcelT.Limits = [0.3 Inf];
            app.DcelT.Value = 0.4;
            app.VelocidadSliderLabel = uilabel(app.ParmetrosPanel_3);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.ParmetrosPanel_3);
            app.VelocidadSlider.Limits = [0 8000];
            app.VelocidadSlider.Value = 2500;
            app.UIAxes = uiaxes(app.PaneldecontrolTab);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, ' ')
            app.UIAxes.XLim = [-600 600];
            app.UIAxes.YLim = [-600 600];
            app.UIAxes.ZLim = [0 800];
            app.Panel = uipanel(app.PaneldecontrolTab);
            app.Panel.Title = 'Panel';
            app.ConexinLampLabel = uilabel(app.Panel);
            app.ConexinLampLabel.Text = 'Conexión';
            app.ConexinLamp = uilamp(app.Panel);
            app.TiemposLabel = uilabel(app.Panel);
            app.TiemposLabel.Text = 'Tiempo (s)';
            app.Time_Indicador = uigauge(app.Panel, 'semicircular');
            app.Time_Indicador.Limits = [0 1];
            app.CargarSiguienteButton = uibutton(app.PaneldecontrolTab, 'push');
            app.CargarSiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSiguienteButtonPushed, true);
            app.CargarSiguienteButton.Text = 'Cargar Siguiente';
            app.CargarFicheroButton = uibutton(app.PaneldecontrolTab, 'push');
            app.CargarFicheroButton.ButtonPushedFcn = createCallbackFcn(app, @CargarFicheroButtonPushed, true);
            app.CargarFicheroButton.Text = 'Cargar Fichero';
            app.CargarAnteriorButton = uibutton(app.PaneldecontrolTab, 'push');
            app.CargarAnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAnteriorButtonPushed, true);
            app.CargarAnteriorButton.Text = 'Cargar Anterior';
            app.RunCheckBox = uicheckbox(app.PaneldecontrolTab);
            app.RunCheckBox.Text = 'Run';
            app.RutaficheroEditFieldLabel = uilabel(app.PaneldecontrolTab);
            app.RutaficheroEditFieldLabel.Text = 'Ruta fichero';
            app.RutaficheroEditField = uieditfield(app.PaneldecontrolTab, 'text');
            app.PuntoLabel = uilabel(app.PaneldecontrolTab);
            app.PuntoLabel.Text = 'Punto:';
            app.ControlIncrementalTab = uitab(app.TabGroup);
            app.ControlIncrementalTab.Title = 'Control Incremental';
            app.PosicinPanel_2 = uipanel(app.ControlIncrementalTab);
            app.PosicinPanel_2.Title = 'Posición';
            app.Mot_1 = uispinner(app.PosicinPanel_2);
            app.Mot_1.Limits = [0 36];
            app.Dist_1 = uispinner(app.PosicinPanel_2);
            app.Dist_1.Step = 0.1;
            app.Dist_1.Limits = [-300 300];
            app.Dist_1.ValueDisplayFormat = '%.2f';
            app.CableLabel = uilabel(app.PosicinPanel_2);
            app.CableLabel.Text = 'Cable';
            app.MotorLabel = uilabel(app.PosicinPanel_2);
            app.MotorLabel.Text = 'Motor';
            app.DistanciammLabel = uilabel(app.PosicinPanel_2);
            app.DistanciammLabel.Text = 'Distancia (mm)';
            app.Label_8 = uilabel(app.PosicinPanel_2);
            app.Label_8.Text = '1';
            app.Mot_2 = uispinner(app.PosicinPanel_2);
            app.Mot_2.Limits = [0 36];
            app.Dist_2 = uispinner(app.PosicinPanel_2);
            app.Dist_2.Step = 0.1;
            app.Dist_2.Limits = [-300 300];
            app.Dist_2.ValueDisplayFormat = '%.2f';
            app.Label_9 = uilabel(app.PosicinPanel_2);
            app.Label_9.Text = '2';
            app.Mot_3 = uispinner(app.PosicinPanel_2);
            app.Mot_3.Limits = [0 36];
            app.Dist_3 = uispinner(app.PosicinPanel_2);
            app.Dist_3.Step = 0.1;
            app.Dist_3.Limits = [-300 300];
            app.Dist_3.ValueDisplayFormat = '%.2f';
            app.Label_10 = uilabel(app.PosicinPanel_2);
            app.Label_10.Text = '3';
            app.GoButton = uibutton(app.ControlIncrementalTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.PosicinPanel_3 = uipanel(app.ControlIncrementalTab);
            app.PosicinPanel_3.Title = 'Posición';
            app.Dir1_2 = uispinner(app.PosicinPanel_3);
            app.Dir1_2.Limits = [0 365];
            app.Amp1_2 = uispinner(app.PosicinPanel_3);
            app.Amp1_2.Limits = [-33 33];
            app.RtulaLabel_2 = uilabel(app.PosicinPanel_3);
            app.RtulaLabel_2.Text = 'Rótula';
            app.DireccinLabel_2 = uilabel(app.PosicinPanel_3);
            app.DireccinLabel_2.Text = 'Dirección';
            app.AmplitudLabel_2 = uilabel(app.PosicinPanel_3);
            app.AmplitudLabel_2.Text = 'Amplitud';
            app.Label_11 = uilabel(app.PosicinPanel_3);
            app.Label_11.Text = '1';
            app.Dir2_2 = uispinner(app.PosicinPanel_3);
            app.Dir2_2.Limits = [0 365];
            app.Amp2_2 = uispinner(app.PosicinPanel_3);
            app.Amp2_2.Limits = [-33 33];
            app.Label_12 = uilabel(app.PosicinPanel_3);
            app.Label_12.Text = '2';
            app.Dir3_2 = uispinner(app.PosicinPanel_3);
            app.Dir3_2.Limits = [0 365];
            app.Dir4_2 = uispinner(app.PosicinPanel_3);
            app.Dir4_2.Limits = [0 365];
            app.Dir5_2 = uispinner(app.PosicinPanel_3);
            app.Dir5_2.Limits = [0 365];
            app.Dir6_2 = uispinner(app.PosicinPanel_3);
            app.Dir6_2.Limits = [0 365];
            app.Dir7_2 = uispinner(app.PosicinPanel_3);
            app.Dir7_2.Limits = [0 365];
            app.Amp3_2 = uispinner(app.PosicinPanel_3);
            app.Amp3_2.Limits = [-33 33];
            app.Amp4_2 = uispinner(app.PosicinPanel_3);
            app.Amp4_2.Limits = [-33 33];
            app.Amp5_2 = uispinner(app.PosicinPanel_3);
            app.Amp5_2.Limits = [-33 33];
            app.Amp6_2 = uispinner(app.PosicinPanel_3);
            app.Amp6_2.Limits = [-33 33];
            app.Amp7_2 = uispinner(app.PosicinPanel_3);
            app.Amp7_2.Limits = [-33 33];
            app.Label_13 = uilabel(app.PosicinPanel_3);
            app.Label_13.Text = '3';
            app.Label_14 = uilabel(app.PosicinPanel_3);
            app.Label_14.Text = '4';
            app.Label_15 = uilabel(app.PosicinPanel_3);
            app.Label_15.Text = '5';
            app.Label_16 = uilabel(app.PosicinPanel_3);
            app.Label_16.Text = '6';
            app.Label_17 = uilabel(app.PosicinPanel_3);
            app.Label_17.Text = '7';
            app.ParmetrosPanel_4 = uipanel(app.ControlIncrementalTab);
            app.ParmetrosPanel_4.Title = 'Parámetros';
            app.AcelTsLabel_2 = uilabel(app.ParmetrosPanel_4);
            app.AcelTsLabel_2.Text = 'AcelT (s)';
            app.AcelT_2 = uieditfield(app.ParmetrosPanel_4, 'numeric');
            app.AcelT_2.Limits = [0.3 Inf];
            app.AcelT_2.Value = 0.5;
            app.DcelTsLabel_2 = uilabel(app.ParmetrosPanel_4);
            app.DcelTsLabel_2.Text = 'DcelT (s)';
            app.DcelT_2 = uieditfield(app.ParmetrosPanel_4, 'numeric');
            app.DcelT_2.Limits = [0.3 Inf];
            app.DcelT_2.Value = 0.5;
            app.VelocidadSlider_2Label = uilabel(app.ParmetrosPanel_4);
            app.VelocidadSlider_2Label.Text = 'Velocidad';
            app.VelocidadSlider_2 = uislider(app.ParmetrosPanel_4);
            app.VelocidadSlider_2.Limits = [0 8000];
            app.VelocidadSlider_2.Value = 1500;
            app.TipoButtonGroup = uibuttongroup(app.ControlIncrementalTab);
            app.TipoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipoButtonGroupSelectionChanged, true);
            app.TipoButtonGroup.Title = 'Tipo';
            app.DistanciaIncrementalButton = uiradiobutton(app.TipoButtonGroup);
            app.DistanciaIncrementalButton.Text = 'Distancia Incremental';
            app.DistanciaIncrementalButton.Value = true;
            app.nguloIncrementalButton = uiradiobutton(app.TipoButtonGroup);
            app.nguloIncrementalButton.Text = 'Ángulo Incremental';
            app.UnidadesButtonGroup = uibuttongroup(app.ControlIncrementalTab);
            app.UnidadesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnidadesButtonGroupSelectionChanged, true);
            app.UnidadesButtonGroup.Title = 'Unidades';
            app.mmButton = uiradiobutton(app.UnidadesButtonGroup);
            app.mmButton.Text = 'mm';
            app.mmButton.Value = true;
            app.PulsosButton = uiradiobutton(app.UnidadesButtonGroup);
            app.PulsosButton.Text = 'Pulsos';
            app.PosicionesButton = uibutton(app.ControlIncrementalTab, 'push');
            app.PosicionesButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionesButtonPushed, true);
            app.PosicionesButton.Text = 'Posiciones';
            app.ComunicacionTab = uitab(app.TabGroup);
            app.ComunicacionTab.Title = 'Comunicación';
            app.ParmetrosPanel = uipanel(app.ComunicacionTab);
            app.ParmetrosPanel.Title = 'Parámetros';
            app.PuertoDropDownLabel = uilabel(app.ParmetrosPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ParmetrosPanel);
            app.Puerto.Items = {};
            app.Puerto.Value = {};
            app.BaudrateLabel = uilabel(app.ParmetrosPanel);
            app.BaudrateLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.ParmetrosPanel);
            app.Baudrate.Items = {};
            app.Baudrate.Value = {};
            app.ConectarButton = uibutton(app.ParmetrosPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.RButton = uibutton(app.ParmetrosPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOM, true);
            app.RButton.Text = 'R';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IHM_Control_Robot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DumboControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DumboControlUIFigure)
        end
    end
end